classdef panels_exp_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavedirectoryLabel              matlab.ui.control.Label
        SaveDirEditField                matlab.ui.control.EditField
        SaveDirBrowseButton             matlab.ui.control.Button
        ExperimentnameEditFieldLabel    matlab.ui.control.Label
        ExpNameEditField                matlab.ui.control.EditField
        Using2PCheckBox                 matlab.ui.control.CheckBox
        ExperimentnumberEditFieldLabel  matlab.ui.control.Label
        ExpNumEditField                 matlab.ui.control.EditField
        TrialdurationsecEditFieldLabel  matlab.ui.control.Label
        TrialDurationEditField          matlab.ui.control.EditField
        PanelsConfigPane                matlab.ui.container.Panel
        PatternfileLabel                matlab.ui.control.Label
        PatternFileEditField            matlab.ui.control.EditField
        PatternFileBrowseButton         matlab.ui.control.Button
        DisplayrateHzLabel              matlab.ui.control.Label
        DisplayRateEditField            matlab.ui.control.EditField
        InitialpatternpositionxyEditFieldLabel  matlab.ui.control.Label
        InitialPatternPosEditField      matlab.ui.control.EditField
        PanelsmodeLabel                 matlab.ui.control.Label
        PanelsModeDropDown              matlab.ui.control.DropDown
        XDimPosFunLabel                 matlab.ui.control.Label
        XDimPosFunEditField             matlab.ui.control.EditField
        YDimPosFunLabel                 matlab.ui.control.Label
        YDimPosFunEditField             matlab.ui.control.EditField
        XDimBrowseButton                matlab.ui.control.Button
        YDimBrowseButton                matlab.ui.control.Button
        OptoStimPane                    matlab.ui.container.Panel
        OptostimtimingstartdurationISIEditFieldLabel  matlab.ui.control.Label
        OptoStimTimingEditField         matlab.ui.control.EditField
        LoadTimingFileButton            matlab.ui.control.Button
        UsePWMCheckBox                  matlab.ui.control.CheckBox
        DutyCycleLabel                  matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.EditField
        DCRangeLabel                    matlab.ui.control.Label
        PWMFreqButtonGroup              matlab.ui.container.ButtonGroup
        Hz100Button                     matlab.ui.control.RadioButton
        Hz1000Button                    matlab.ui.control.RadioButton
        PWMFreqLabel                    matlab.ui.control.Label
        UsingOptoStimCheckBox           matlab.ui.control.CheckBox
        UsingPanelsCheckBox             matlab.ui.control.CheckBox
        StartTrialButton                matlab.ui.control.Button
        StopTrialButton                 matlab.ui.control.Button
        FicTracconfigfileLabel          matlab.ui.control.Label
        FicTracConfigEditField          matlab.ui.control.EditField
        FicTracConfigBrowseButton       matlab.ui.control.Button
        CreateExpDirButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        
%         % Default paths for browse buttons
%         defaultSavePath = 'C:\Users\Wilson Lab\Desktop\Michael\Data';
%         defaultPatternFilePath = 'C:\Users\Wilson Lab\Desktop\Michael\2p_acq_code\Panels_code\Patterns_and_functions\patterns';
%         defaultPositionFunctionPath = 'C:\Users\Wilson Lab\Desktop\Michael\2p_acq_code\Panels_code\Patterns_and_functions\functions';
%         defaultTimingFilePath = 'C:\Users\Wilson Lab\Documents\MATLAB\2P_acq_code\';
        

        defaultSavePath = 'C:\Users\Wilson Lab\Desktop\Michael\Data';
        defaultConfigFilePath = 'C:\Users\Wilson Lab\Desktop\Michael';
        defaultPatternFilePath = 'C:\Users\Wilson Lab\Desktop\Michael\2p_acq_code\Panels_code\Patterns_and_functions\patterns';
        defaultPositionFunctionPath = 'C:\Users\Wilson Lab\Desktop\Michael\2p_acq_code\Panels_code\Patterns_and_functions\functions';
        defaultTimingFilePath = 'C:\Users\Wilson Lab\Desktop\Michael';
    end
    
    properties (Access = public)
        sessionObject % Holds the session object that will run the experiment so that it can be interrupted by the stop button
        trialSettings % Structure to hold trial paramters to pass to run_panels_trial() function
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Make sure panels and toggled components are initially not visible
            app.PanelsConfigPane.Visible = 'off';
            app.XDimPosFunLabel.Visible = 'off';
            app.YDimPosFunLabel.Visible = 'off';
            app.XDimPosFunEditField.Visible = 'off';
            app.YDimPosFunEditField.Visible = 'off';
            app.XDimBrowseButton.Visible = 'off';
            app.YDimBrowseButton.Visible = 'off';
            
            app.OptoStimPane.Visible = 'off';
            app.PWMFreqLabel.Visible = 'off';
            app.PWMFreqButtonGroup.Visible = 'off';
            app.DutyCycleLabel.Visible = 'off';
            app.DutyCycleEditField.Visible = 'off';
            app.DCRangeLabel.Visible = 'off';
            
            % Initialize trialSettings object
            app.trialSettings.saveDir = [];
            app.trialSettings.expNum = [];
            app.trialSettings.expName = [];
            app.trialSettings.trialDuration = [];
            app.trialSettings.ftConfigFile = [];
            app.trialSettings.using2P = 0;
            app.trialSettings.usingPanels = 0;
            app.trialSettings.pattern = [];
            app.trialSettings.patternNum = [];
            app.trialSettings.initialPosition = [];
            app.trialSettings.displayRate = [];
            app.trialSettings.panelsMode = [];
            app.trialSettings.xDimPosFun = [];
            app.trialSettings.xDimPosFunNum = [];
            app.trialSettings.yDimPosFun = [];
            app.trialSettings.yDimPosFunNum = [];
            app.trialSettings.usingOptoStim = 0;
            app.trialSettings.optoStimTiming = [];
            app.trialSettings.usePWM = 0;
            app.trialSettings.PWMFreq = 1000;
            app.trialSettings.PWMDutyCycle = [];
            
        end

        % Button pushed function: SaveDirBrowseButton
        function SaveDirBrowseButtonPushed(app, event)
            
            % Prompt user to select a directory to save data in (a new folder will be created within that directory for each exp)
            if isdir(app.defaultSavePath)
                defaultDir = app.defaultSavePath;
            else
                defaultDir = 'C:\Users';
            end
            saveDir = uigetdir(defaultDir, 'Select a save directory');
            if saveDir
                app.SaveDirEditField.Value = saveDir;
                app.trialSettings.saveDir = saveDir;
            end
        end

        % Value changed function: ExpNumEditField
        function ExpNumEditFieldValueChanged(app, event)
            
            value = app.ExpNumEditField.Value;
            
            if ~isempty(value)
                
                value = int16(str2double(value));
                    
                % Make sure value is an integer before adding to trialSettings
                if ~isinteger(value) || value < 1
                    uialert(app.UIFigure, 'Error: invalid experiment number! Please enter a positive integer.', ...
                            'Invalid value');
                    app.ExpNumEditField.Value = '';
                    app.trialSettings.expNum = [];
                else
                    app.trialSettings.expNum = value;
                end
            else
                % If it returned nan the value was probably an empty string, so update trialSettings.
                app.trialSettings.expNum = [];
            end
        end

        % Button pushed function: StartTrialButton
        function StartTrialButtonPushed(app, event)
            
            disp(app.trialSettings);
            
            % ---- VALIDATION TESTS FOR REQUIRED FIELDS ----
            ts = app.trialSettings;
            
            if isempty(ts.saveDir)
               uialert(app.UIFigure, 'Please select a save directory!', 'Missing parameter'); 
               return;
            elseif isempty(ts.expNum)
               uialert(app.UIFigure, 'Please enter an experiment number!', 'Missing parameter'); 
               return;
            elseif isempty(ts.expName)
               uialert(app.UIFigure, 'Please enter an experiment name!', 'Missing parameter'); 
               return;
            elseif isempty(ts.trialDuration)
               uialert(app.UIFigure, 'Please enter a trial duration!', 'Missing parameter'); 
            elseif isempty(ts.ftConfigFile)
               uialert(app.UIFigure, 'Please select a FicTrac config file!', 'Missing parameter');
               return;
            elseif ts.usingPanels && isempty(ts.patternNum)
               uialert(app.UIFigure, 'Please enter a panels pattern number!', 'Missing parameter'); 
               return;
            elseif ts.usingPanels && isempty(ts.displayRate)
               uialert(app.UIFigure, 'Please enter a display update rate!', 'Missing parameter'); 
               return;
            elseif ts.usingPanels && isempty(ts.initialPosition)
               uialert(app.UIFigure, 'Please enter an initial position for the panels pattern!', 'Missing parameter'); 
               return;
            elseif ts.usingPanels && strcmpi(ts.panelsMode, 'open loop') && (isempty(ts.xDimPosFunNum) || isempty(ts.yDimPosFunNum))
               uialert(app.UIFigure, 'Please select both X and Y dimension position functions!', 'Missing parameter'); 
               return;
            elseif ts.usingOptoStim && isempty(ts.optoStimTiming)
               uialert(app.UIFigure, 'Please enter or load opto stim timing info!', 'Missing parameter'); 
               return;                
            elseif ts.usingOptoStim && ts.usePWM && isempty(ts.PWMDutyCycle)
               uialert(app.UIFigure, 'Please enter a PWM duty cycle!', 'Missing parameter'); 
               return; 
            end
            
            % Start trial
            setup_panels_trial(app.trialSettings);
        end

        % Value changed function: ExpNameEditField
        function ExpNameEditFieldValueChanged(app, event)
            
            value = app.ExpNameEditField.Value;
            
            % Make sure the name can be used in a directory path before adding to trial settings
            if ~isempty(regexp(value, '.*[|.*?<>":\\/].*', 'once'))
                uialert(app.UIFigure, 'Error: experiment name must be a valid directory path! Please remove any of the following characters: | . * ? < > " : \ /', ...
                        'Invalid value');
                app.ExpNameEditField.Value = '';
                app.trialSettings.expName = [];
            else
                app.trialSettings.expName = value;
            end
            
            
        end

        % Value changed function: TrialDurationEditField
        function TrialDurationEditFieldValueChanged(app, event)
            
            value = app.TrialDurationEditField.Value;
            
            if ~isempty(value)
                
                value = str2double(value);
                  
                % Make sure value is an integer
                if floor(value) ~= value || value < 1
                    uialert(app.UIFigure, 'Error: invalid trial duration! Please enter a positive integer.', ...
                            'Invalid value');
                    app.TrialDurationEditField.Value = '';
                    app.trialSettings.trialDuration = [];
                else
                    app.trialSettings.trialDuration = value;
                end
            else
                % If it returned nan the value was probably an empty string, so update trialSettings.
                app.trialSettings.trialDuration = [];
            end
        end

        % Value changed function: UsingPanelsCheckBox
        function UsingPanelsCheckBoxValueChanged(app, event)
            value = app.UsingPanelsCheckBox.Value;
            app.trialSettings.usingPanels = value;
            
            % Adjust visibility of panels configuration pane
            if value
                app.PanelsConfigPane.Visible = 'on';
            else
                app.PanelsConfigPane.Visible = 'off';
            end
            
        end

        % Value changed function: UsingOptoStimCheckBox
        function UsingOptoStimCheckBoxValueChanged(app, event)
            value = app.UsingOptoStimCheckBox.Value;
            app.trialSettings.usingOptoStim = value;
            
            % Adjust visibility of opto stim configuration pane
            if value
                app.OptoStimPane.Visible = 'on';
            else
                app.OptoStimPane.Visible = 'off';
            end
            
        end

        % Button pushed function: PatternFileBrowseButton
        function PatternFileBrowseButtonPushed(app, event)
            
            % Prompt user to select a pattern file
            [fileName, pathName] = uigetfile(fullfile(app.defaultPatternFilePath, 'Pattern_*.mat'));
            
            disp(['fileName = ', fileName])
            disp(['pathName = ', pathName])
            
            % Display file name and extract pattern number
            if fileName
                if ~isempty(fileName) 
                    patternNum = regexp(fileName, '(?<=Pattern_)...(?=_.*)', 'match');
                    if ~isempty(patternNum)
                        patternData = load(fullfile(pathName, fileName)); % Save raw pattern data to settings
                        app.trialSettings.pattern = [];
                        app.trialSettings = setstructfields(app.trialSettings, patternData);
                        patternNum = patternNum{:};
                        app.trialSettings.patternNum = str2double(patternNum);
                        app.PatternFileEditField.Value = fileName;
                    else
                        % Thow error if file name was invalid
                        uialert(app.UIFigure, 'Error: invalid file name! Could not find pattern number in file name.', ...
                                'Invalid value');
                    end
                end
            end
            
        end

        % Value changed function: DisplayRateEditField
        function DisplayRateEditFieldValueChanged(app, event)
            
            value = app.DisplayRateEditField.Value;
            
            if ~isempty(value)
                
                value = int16(str2double(value));
                
                % Make sure value is an integer
                if ~isinteger(value) || value < 1
                    uialert(app.UIFigure, 'Error: this is not a valid display rate! Please enter a positive integer.', ...
                            'Invalid value');
                    app.DisplayRateEditField.Value = '';
                    app.trialSettings.displayRate = [];
                else
                    app.trialSettings.displayRate = value;
                end
                
            else
                % If it returned nan the value was probably an empty string, so update trialSettings.
                app.trialSettings.displayRate = [];
            end
            
        end

        % Value changed function: InitialPatternPosEditField
        function InitialPatternPosEditFieldValueChanged(app, event)
            
            value = str2num(app.InitialPatternPosEditField.Value);
            
            % Make sure value is valid
            if isempty(value) || numel(value) ~= 2
                uialert(app.UIFigure, 'Error: invalid initial position! Please enter a 2-element integer array', 'Invalid value')
                app.InitialPatternPosEditField.Value = '[ ]';
                app.trialSettings.initialPosition = [];
            else
                app.trialSettings.initialPosition = value;
            end
            
        end

        % Value changed function: Using2PCheckBox
        function Using2PCheckBoxValueChanged(app, event)
            
            value = app.Using2PCheckBox.Value;
            app.trialSettings.using2P = value;
            
        end

        % Value changed function: PanelsModeDropDown
        function PanelsModeDropDownValueChanged(app, event)
            
            value = app.PanelsModeDropDown.Value;
            
            if strcmpi(value, 'open loop')
                
                app.trialSettings.panelsMode = 'open loop';
                
                % Adjust component visibility
                app.XDimPosFunLabel.Visible = 'on';
                app.YDimPosFunLabel.Visible = 'on';
                app.XDimPosFunEditField.Visible = 'on';
                app.YDimPosFunEditField.Visible = 'on';
                app.XDimBrowseButton.Visible = 'on';
                app.YDimBrowseButton.Visible = 'on';
                
            elseif strcmpi(value, 'closed loop')
                
                app.trialSettings.panelsMode = 'closed loop';
                
                % Adjust component visibility
                app.XDimPosFunLabel.Visible = 'off';
                app.YDimPosFunLabel.Visible = 'off';
                app.XDimPosFunEditField.Visible = 'off';
                app.YDimPosFunEditField.Visible = 'off';
                app.XDimBrowseButton.Visible = 'off';
                app.YDimBrowseButton.Visible = 'off';
                
            else
                app.trialSettings.panelsMode = [];
            end
        end

        % Button pushed function: XDimBrowseButton
        function XDimBrowseButtonPushed(app, event)
            
            % Prompt user to select a position function file
            [fileName, pathName] = uigetfile(fullfile(app.defaultPositionFunctionPath, 'position_*.mat'));
            
            % Display file name and extract pattern number
            if fileName
                posFunNum = regexp(fileName, '(?<=position_function_)...(?=_.*)', 'match');
                if ~isempty(posFunNum)
                    app.trialSettings.xDimPosFun = load(fullfile(pathName, fileName)); % Save position function vector
                    posFunNum = posFunNum{:};
                    app.trialSettings.xDimPosFunNum = str2double(posFunNum);
                    app.XDimPosFunEditField.Value = fileName;
                else
                    % Thow error if file name was invalid
                    uialert(app.UIFigure, 'Error: invalid file name! Could not find position function number in file name.', ...
                            'Invalid value');
                end
            end
            
        end

        % Button pushed function: YDimBrowseButton
        function YDimBrowseButtonPushed(app, event)
            
            % Prompt user to select a position function file
            [fileName, pathName] = uigetfile(fullfile(app.defaultPositionFunctionPath, 'position_*.mat'));
            
            % Display file name and extract pattern number
            if fileName
                posFunNum = regexp(fileName, '(?<=position_function_)...(?=_.*)', 'match');
                if ~isempty(posFunNum)
                    app.trialSettings.yDimPosFun = load(fullfile(pathName, fileName)); % Save position function vector
                    posFunNum = posFunNum{:};
                    app.trialSettings.yDimPosFunNum = str2double(posFunNum);
                    app.YDimPosFunEditField.Value = fileName;
                else
                    % Thow error if file name was invalid
                    uialert(app.UIFigure, 'Error: invalid file name! Could not find position function number in file name.', ...
                        'Invalid value');
                end
            end
            
        end

        % Value changed function: OptoStimTimingEditField
        function OptoStimTimingEditFieldValueChanged(app, event)
            
            value = str2num(app.OptoStimTimingEditField.Value);
            
            % Make sure value is valid
            if isempty(value) || numel(value) ~= 3
                uialert(app.UIFigure, 'Error: invalid timing value! Please enter a 3-element integer array', 'Invalid value')
                app.OptoStimTimingEditField.Value = '[ ]';
                app.trialSettings.optoStimTiming = [];
            else
                app.trialSettings.optoStimTiming = value;
            end
            
        end

        % Button pushed function: LoadTimingFileButton
        function LoadTimingFileButtonPushed(app, event)
            
            % Prompt user to select a timing data file
            [fileName, pathName] = uigetfile(fullfile(app.defaultPositionFunctionPath, '*.mat'));
            if fileName
                try
                    timingData = load(fullfile(pathName, fileName));
                    % TODO: add validation checks on the contents of this file if I ever end up using this feature.
                    app.OptoStimTimingEditField.Value = 'Custom';
                    app.trialSettings.optoStimTiming = timingData;
                catch
                    uialert(app.UIFigure, 'Error: failed to load timing data file.', 'Error');
                end
            end
            
        end

        % Value changed function: UsePWMCheckBox
        function UsePWMCheckBoxValueChanged(app, event)
            value = app.UsePWMCheckBox.Value;
            app.trialSettings.usePWM = value;
            
            % Adjust component visibility
            if value
                app.PWMFreqLabel.Visible = 'on';
                app.PWMFreqButtonGroup.Visible = 'on';
                app.DutyCycleLabel.Visible = 'on';
                app.DutyCycleEditField.Visible = 'on';
                app.DCRangeLabel.Visible = 'on';
            else
                app.PWMFreqLabel.Visible = 'off';
                app.PWMFreqButtonGroup.Visible = 'off';
                app.DutyCycleLabel.Visible = 'off';
                app.DutyCycleEditField.Visible = 'off';
                app.DCRangeLabel.Visible = 'off';
            end
        end

        % Selection changed function: PWMFreqButtonGroup
        function PWMFreqButtonGroupSelectionChanged(app, event)
            selectedButton = app.PWMFreqButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, '1000 Hz')
                
                app.trialSettings.PWMFreq = 1000;
                
                % Make sure change didn't invalidate the PWM duty cycle value
                dcVal = str2double(app.DutyCycleEditField.Value);
                if ~isnan(dcVal)
                    if dcVal < 20 || dcVal > 80
                        uialert(app.UIFigure, 'Error: PWM duty cycle is no longer valid for the selected PWM frequency...please enter a new PWM duty cycle.', ...
                                'Invalid value');
                        app.DutyCycleEditField.Value = '';
                        app.trialSettings.PWMDutyCycle = [];
                    end
                end
            
            else
                app.trialSettings.PWMFreq = 100;
            end            
            
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            
            value = app.DutyCycleEditField.Value;
            
            if ~isempty(value)
                
                value = int16(str2double(value));
                        
                % Make sure value is an integer
                if ~isinteger(value) || value < 1
                    uialert(app.UIFigure, 'Error: this is not a valid duty cycle value! Please enter a positive integer within the appropriate range.', ...
                            'Invalid value');
                    app.DutyCycleEditField.Value = '';
                    app.trialSettings.PWMDutyCycle = [];
                    
                    % Make sure value is within acceptable range for LED performance
                elseif (app.trialSettings.PWMFreq == 100 && (value < 2 || value > 98)) || ...
                            app.trialSettings.PWMFreq == 1000 && (value < 20 || value > 80)
                    uialert(app.UIFigure, 'Error: invalid duty cycle value! Please ensure that the value is within the specified range for the selected PWM frequency.', ...
                            'Invalid value');
                    app.DutyCycleEditField.Value = '';
                    app.trialSettings.PWMDutyCycle = [];
                else
                    app.trialSettings.PWMDutyCycle = value;
                end
            else
                % If it returned nan the value was probably an empty string, so update trialSettings.
                app.trialSettings.PWMDutyCycle.Value = [];
            end
            
        end

        % Button pushed function: StopTrialButton
        function StopTrialButtonPushed(app, event)
            
            % Attempt to interrupt running session object
            
            global s % This is the session object that was created in start_panels_trial()
            
            try
                s.stop();
                release(s);
                disp('Stopped acquisition');
            catch
            end
            
        end

        % Button pushed function: FicTracConfigBrowseButton
        function FicTracConfigBrowseButtonPushed(app, event)
            
            % Prompt user to select a FicTrac config file
            [fileName, pathName] = uigetfile(fullfile(app.defaultConfigFilePath, '*.txt'));
            
            % Display file name
            if fileName
                if ~isempty(fileName) 
                    app.trialSettings.ftConfigFile = fullfile(pathName, fileName);
                    app.FicTracConfigEditField.Value = fullfile(pathName, fileName);
                end
            end
        end

        % Button pushed function: CreateExpDirButton
        function CreateExpDirButtonPushed(app, event)
            
            % Make sure all the required information is present
            if isempty(app.trialSettings.saveDir)
               uialert(app.UIFigure, 'Please select a save directory!', 'Missing parameter'); 
               return;
            elseif isempty(app.trialSettings.expNum)
               uialert(app.UIFigure, 'Please enter an experiment number!', 'Missing parameter'); 
               return;
            elseif isempty(app.trialSettings.expName)
               uialert(app.UIFigure, 'Please enter an experiment name!', 'Missing parameter'); 
               return;
            end
            
            % Check whether there is an existing directory with the same date and exp num
            expID = [datestr(now, 'yyyymmdd'), '-', num2str(app.trialSettings.expNum)];
            expIDDir = dir(fullfile(app.trialSettings.saveDir, [expID, '*']));
            
            % If yes, make sure it also has the same experiment name and display error if it doesn't. 
            % If no, create a new directory for the experiment.
            expDirName = [expID, '_', regexprep(app.trialSettings.expName, '\s', '-')];
            if ~isempty(expIDDir) && ~strcmp(expIDDir(1).name, expDirName)
                errordlg('Error: an experiment with the same date and number but a different name', ...
                            ' already exists in the save directory...please enter a different experiment name or number.', 'Error')
                return
            elseif ~isdir(fullfile(app.trialSettings.saveDir, expDirName))
                % Create the experiment directory if it does not already exist
                mkdir(fullfile(app.trialSettings.saveDir, expDirName));
                disp('Creating new experiment directory...')
            end           
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Panels experiment GUI';
            app.SavedirectoryLabel = uilabel(app.UIFigure);
            app.SavedirectoryLabel.Text = 'Save directory:';
            app.SaveDirEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirEditField.Editable = 'off';
            app.SaveDirBrowseButton = uibutton(app.UIFigure, 'push');
            app.SaveDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDirBrowseButtonPushed, true);
            app.SaveDirBrowseButton.Text = 'Browse';
            app.ExperimentnameEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name:';
            app.ExpNameEditField = uieditfield(app.UIFigure, 'text');
            app.ExpNameEditField.ValueChangedFcn = createCallbackFcn(app, @ExpNameEditFieldValueChanged, true);
            app.Using2PCheckBox = uicheckbox(app.UIFigure);
            app.Using2PCheckBox.ValueChangedFcn = createCallbackFcn(app, @Using2PCheckBoxValueChanged, true);
            app.Using2PCheckBox.Text = ' Using 2P?';
            app.ExperimentnumberEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentnumberEditFieldLabel.Text = 'Experiment number:';
            app.ExpNumEditField = uieditfield(app.UIFigure, 'text');
            app.ExpNumEditField.ValueChangedFcn = createCallbackFcn(app, @ExpNumEditFieldValueChanged, true);
            app.TrialdurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.TrialdurationsecEditFieldLabel.Text = 'Trial duration (sec):';
            app.TrialDurationEditField = uieditfield(app.UIFigure, 'text');
            app.TrialDurationEditField.ValueChangedFcn = createCallbackFcn(app, @TrialDurationEditFieldValueChanged, true);
            app.PanelsConfigPane = uipanel(app.UIFigure);
            app.PanelsConfigPane.Title = 'Panels configuration';
            app.PatternfileLabel = uilabel(app.PanelsConfigPane);
            app.PatternfileLabel.Text = 'Pattern file:';
            app.PatternFileEditField = uieditfield(app.PanelsConfigPane, 'text');
            app.PatternFileEditField.Editable = 'off';
            app.PatternFileBrowseButton = uibutton(app.PanelsConfigPane, 'push');
            app.PatternFileBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @PatternFileBrowseButtonPushed, true);
            app.PatternFileBrowseButton.Text = 'Browse';
            app.DisplayrateHzLabel = uilabel(app.PanelsConfigPane);
            app.DisplayrateHzLabel.Text = 'Display rate (Hz):';
            app.DisplayRateEditField = uieditfield(app.PanelsConfigPane, 'text');
            app.DisplayRateEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayRateEditFieldValueChanged, true);
            app.InitialpatternpositionxyEditFieldLabel = uilabel(app.PanelsConfigPane);
            app.InitialpatternpositionxyEditFieldLabel.Text = 'Initial pattern position [x, y]';
            app.InitialPatternPosEditField = uieditfield(app.PanelsConfigPane, 'text');
            app.InitialPatternPosEditField.ValueChangedFcn = createCallbackFcn(app, @InitialPatternPosEditFieldValueChanged, true);
            app.InitialPatternPosEditField.Value = '[ ]';
            app.PanelsmodeLabel = uilabel(app.PanelsConfigPane);
            app.PanelsmodeLabel.Text = 'Panels mode:';
            app.PanelsModeDropDown = uidropdown(app.PanelsConfigPane);
            app.PanelsModeDropDown.Items = {'', 'Open loop', 'Closed loop'};
            app.PanelsModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PanelsModeDropDownValueChanged, true);
            app.PanelsModeDropDown.Value = '';
            app.XDimPosFunLabel = uilabel(app.PanelsConfigPane);
            app.XDimPosFunLabel.Text = 'X position function:';
            app.XDimPosFunEditField = uieditfield(app.PanelsConfigPane, 'text');
            app.XDimPosFunEditField.Editable = 'off';
            app.YDimPosFunLabel = uilabel(app.PanelsConfigPane);
            app.YDimPosFunLabel.Text = 'Y position function:';
            app.YDimPosFunEditField = uieditfield(app.PanelsConfigPane, 'text');
            app.YDimPosFunEditField.Editable = 'off';
            app.XDimBrowseButton = uibutton(app.PanelsConfigPane, 'push');
            app.XDimBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @XDimBrowseButtonPushed, true);
            app.XDimBrowseButton.Text = 'Browse';
            app.YDimBrowseButton = uibutton(app.PanelsConfigPane, 'push');
            app.YDimBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @YDimBrowseButtonPushed, true);
            app.YDimBrowseButton.Text = 'Browse';
            app.OptoStimPane = uipanel(app.UIFigure);
            app.OptoStimPane.Title = 'Opto stim configuration';
            app.OptostimtimingstartdurationISIEditFieldLabel = uilabel(app.OptoStimPane);
            app.OptostimtimingstartdurationISIEditFieldLabel.Text = 'Opto stim timing - [start, duration, ISI]';
            app.OptoStimTimingEditField = uieditfield(app.OptoStimPane, 'text');
            app.OptoStimTimingEditField.ValueChangedFcn = createCallbackFcn(app, @OptoStimTimingEditFieldValueChanged, true);
            app.OptoStimTimingEditField.Value = '[ ]';
            app.LoadTimingFileButton = uibutton(app.OptoStimPane, 'push');
            app.LoadTimingFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTimingFileButtonPushed, true);
            app.LoadTimingFileButton.Text = 'Load timing file';
            app.UsePWMCheckBox = uicheckbox(app.OptoStimPane);
            app.UsePWMCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsePWMCheckBoxValueChanged, true);
            app.UsePWMCheckBox.Text = 'Use PWM';
            app.DutyCycleLabel = uilabel(app.OptoStimPane);
            app.DutyCycleLabel.Text = 'PWM duty cycle:';
            app.DutyCycleEditField = uieditfield(app.OptoStimPane, 'text');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.DCRangeLabel = uilabel(app.OptoStimPane);
            app.DCRangeLabel.Text = 'Valid range: 2-98 at 100 Hz, 20-80 at 1000 Hz';
            app.PWMFreqButtonGroup = uibuttongroup(app.OptoStimPane);
            app.PWMFreqButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PWMFreqButtonGroupSelectionChanged, true);
            app.Hz100Button = uiradiobutton(app.PWMFreqButtonGroup);
            app.Hz100Button.Text = '100 Hz';
            app.Hz1000Button = uiradiobutton(app.PWMFreqButtonGroup);
            app.Hz1000Button.Text = '1000 Hz';
            app.Hz1000Button.Value = true;
            app.PWMFreqLabel = uilabel(app.OptoStimPane);
            app.PWMFreqLabel.Text = 'PWM freq:';
            app.UsingOptoStimCheckBox = uicheckbox(app.UIFigure);
            app.UsingOptoStimCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsingOptoStimCheckBoxValueChanged, true);
            app.UsingOptoStimCheckBox.Text = ' Using 1P opto stim?';
            app.UsingPanelsCheckBox = uicheckbox(app.UIFigure);
            app.UsingPanelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsingPanelsCheckBoxValueChanged, true);
            app.UsingPanelsCheckBox.Text = ' Using panels?';
            app.StartTrialButton = uibutton(app.UIFigure, 'push');
            app.StartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrialButtonPushed, true);
            app.StartTrialButton.Text = 'Start trial';
            app.StopTrialButton = uibutton(app.UIFigure, 'push');
            app.StopTrialButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrialButtonPushed, true);
            app.StopTrialButton.Text = 'Stop trial';
            app.FicTracconfigfileLabel = uilabel(app.UIFigure);
            app.FicTracconfigfileLabel.Text = 'FicTrac config file:';
            app.FicTracConfigEditField = uieditfield(app.UIFigure, 'text');
            app.FicTracConfigEditField.Editable = 'off';
            app.FicTracConfigBrowseButton = uibutton(app.UIFigure, 'push');
            app.FicTracConfigBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @FicTracConfigBrowseButtonPushed, true);
            app.FicTracConfigBrowseButton.Text = 'Browse';
            app.CreateExpDirButton = uibutton(app.UIFigure, 'push');
            app.CreateExpDirButton.ButtonPushedFcn = createCallbackFcn(app, @CreateExpDirButtonPushed, true);
            app.CreateExpDirButton.Text = 'Create experiment directory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = panels_exp_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end